classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ThemeButtonGroup             matlab.ui.container.ButtonGroup
        RandomButton                 matlab.ui.control.RadioButton
        DarkButton                   matlab.ui.control.RadioButton
        DefaultButton                matlab.ui.control.RadioButton
        NicholasSchlosser32626Label  matlab.ui.control.Label
        GraphicalUserInterfaceandEventDrivenProgrammingLabel  matlab.ui.control.Label
        SensorDataDropDown           matlab.ui.control.DropDown
        SensorDataDropDownLabel      matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Light = readtable('ESG111GUIData.xlsx','Sheet','Light');
        Sound = readtable('ESG111GUIData.xlsx','Sheet','Sound');
        Temperature = readtable('ESG111GUIData.xlsx','Sheet','Temperature');
        W = [0.85 0.85 0.85];
        B = [0 0 0];
        DG = [0.07 0.07 0.07];
        LG = [0.7 0.7 0.7];
        R1 = [0 0 0];
        R2 = [0 0 0];
        R3 = [0 0 0];
        LineColor = [0.85 0.1 0.1];
        LineColor1 = [0.85 0.1 0.1];
        LineColor2 = [0.15 0.55 0.85];
        LineColorR = [0 0 0];
        Sensor;
    end
    
    methods (Access = private)
        %plot sensor data selected in drop down against
        function Y = graph(app, SensorData)
            pY = readtable('ESG111GUIData.xlsx','Sheet', SensorData);
            Y = pY(2:end,:);
            plot(app.UIAxes,table2array(Y),"Color",app.LineColor);
            if SensorData == "Light"
                ylabel(app.UIAxes, 'Intensity (cd)');
                title(app.UIAxes, 'Light Intensity');
            elseif SensorData == "Sound"
                ylabel(app.UIAxes, 'Pressure (Pa)');
                title(app.UIAxes, 'Sound Pressure');
            else 
                ylabel(app.UIAxes, 'Temperature (°F)');
                title(app.UIAxes, 'Temperature')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartUp(app)
                app.GraphicalUserInterfaceandEventDrivenProgrammingLabel.FontColor = app.B;
                app.SensorDataDropDownLabel.FontColor = app.B;
                app.SensorDataDropDownLabel.BackgroundColor = app.W;
                app.UIFigure.Color = app.W;
                app.UIAxes.Color = app.LG;
                app.UIAxes.YColor = app.B;
                app.UIAxes.XColor = app.B;
                app.NicholasSchlosser32626Label.FontColor = app.B;
                app.SensorDataDropDown.FontColor = app.B;
                app.SensorDataDropDown.BackgroundColor = app.LG;
                app.RandomButton.FontColor = app.B;
                app.DefaultButton.FontColor = app.B;
                app.DarkButton.FontColor = app.B;
                app.ThemeButtonGroup.HighlightColor = app.W;
                app.ThemeButtonGroup.BorderColor = app.W;
                app.ThemeButtonGroup.BackgroundColor = app.W;
                app.ThemeButtonGroup.ForegroundColor = app.B;
                DataUpdate(app);
        end

        % Callback function: SensorDataDropDown, SensorDataDropDown, 
        % ...and 1 other component
        function DataUpdate(app, event)
            app.Sensor = app.SensorDataDropDown.Value;
            if app.SensorDataDropDown.Value == "Light Intensity"
                app.Sensor = 'Light';
            elseif app.SensorDataDropDown.Value == "Sound Pressure"
                app.Sensor = 'Sound';
            end
            graph(app,app.Sensor);
        end

        % Callback function: ThemeButtonGroup, ThemeButtonGroup
        function ThemeChange(app, event)
            selectedButton = app.ThemeButtonGroup.SelectedObject;
            if selectedButton == app.DefaultButton
                app.GraphicalUserInterfaceandEventDrivenProgrammingLabel.FontColor = app.B;
                app.SensorDataDropDownLabel.FontColor = app.B;
                app.SensorDataDropDownLabel.BackgroundColor = app.W;
                app.UIFigure.Color = app.W;
                app.UIAxes.Color = app.LG;
                app.UIAxes.YColor = app.B;
                app.UIAxes.XColor = app.B;
                app.NicholasSchlosser32626Label.FontColor = app.B;
                app.SensorDataDropDown.FontColor = app.B;
                app.SensorDataDropDown.BackgroundColor = app.LG;
                app.RandomButton.FontColor = app.B;
                app.DefaultButton.FontColor = app.B;
                app.DarkButton.FontColor = app.B;
                app.ThemeButtonGroup.HighlightColor = app.W;
                app.ThemeButtonGroup.BorderColor = app.W;
                app.ThemeButtonGroup.BackgroundColor = app.W;
                app.ThemeButtonGroup.ForegroundColor = app.B;
                app.LineColor = app.LineColor1;
            elseif selectedButton == app.DarkButton
                app.GraphicalUserInterfaceandEventDrivenProgrammingLabel.FontColor = app.W;
                app.SensorDataDropDownLabel.FontColor = app.W;
                app.SensorDataDropDownLabel.BackgroundColor = app.B;
                app.UIFigure.Color = app.B;
                app.UIAxes.Color = app.DG;
                app.UIAxes.YColor = app.W;
                app.UIAxes.XColor = app.W;
                app.NicholasSchlosser32626Label.FontColor = app.W;
                app.SensorDataDropDown.FontColor = app.W;
                app.SensorDataDropDown.BackgroundColor = app.DG;
                app.RandomButton.FontColor = app.W;
                app.DefaultButton.FontColor = app.W;
                app.DarkButton.FontColor = app.W;
                app.ThemeButtonGroup.HighlightColor = app.B;
                app.ThemeButtonGroup.BorderColor = app.B;
                app.ThemeButtonGroup.BackgroundColor = app.B;
                app.ThemeButtonGroup.ForegroundColor = app.W;
                app.LineColor = app.LineColor2;
            else
                app.R1 = rand(3,1);
                app.R2 = rand(3,1);
                app.R3 = rand(3,1);
                app.LineColorR = rand(3,1);
                app.GraphicalUserInterfaceandEventDrivenProgrammingLabel.FontColor = app.R1;
                app.SensorDataDropDownLabel.FontColor = app.R1;
                app.SensorDataDropDownLabel.BackgroundColor = app.R2;
                app.UIFigure.Color = app.R2;
                app.UIAxes.Color = app.R3;
                app.UIAxes.YColor = app.R1;
                app.UIAxes.XColor = app.R1;
                app.NicholasSchlosser32626Label.FontColor = app.R1;
                app.SensorDataDropDown.FontColor = app.R1;
                app.SensorDataDropDown.BackgroundColor = app.R3;
                app.RandomButton.FontColor = app.R1;
                app.DefaultButton.FontColor = app.R1;
                app.DarkButton.FontColor = app.R1;
                app.ThemeButtonGroup.HighlightColor = app.R2;
                app.ThemeButtonGroup.BorderColor = app.R2;
                app.ThemeButtonGroup.BackgroundColor = app.R2;
                app.ThemeButtonGroup.ForegroundColor = app.R1;
                app.LineColor = app.LineColorR;
            end
            graph(app,app.Sensor);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SensorDataDropDownLabel = uilabel(app.UIFigure);
            app.SensorDataDropDownLabel.Text = 'Sensor Data';
            app.SensorDataDropDown = uidropdown(app.UIFigure);
            app.SensorDataDropDown.Items = {'Temperature', 'Light Intensity', 'Sound Pressure'};
            app.SensorDataDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DataUpdate, true);
            app.SensorDataDropDown.ValueChangedFcn = createCallbackFcn(app, @DataUpdate, true);
            app.SensorDataDropDown.ClickedFcn = createCallbackFcn(app, @DataUpdate, true);
            app.SensorDataDropDown.Value = 'Temperature';
            app.GraphicalUserInterfaceandEventDrivenProgrammingLabel = uilabel(app.UIFigure);
            app.GraphicalUserInterfaceandEventDrivenProgrammingLabel.Text = 'Graphical User Interface and Event-Driven Programming';
            app.NicholasSchlosser32626Label = uilabel(app.UIFigure);
            app.NicholasSchlosser32626Label.Text = 'Nicholas Schlosser 3/26/26';
            app.ThemeButtonGroup = uibuttongroup(app.UIFigure);
            app.ThemeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ThemeChange, true);
            app.ThemeButtonGroup.Title = 'Theme';
            app.ThemeButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ThemeChange, true);
            app.DefaultButton = uiradiobutton(app.ThemeButtonGroup);
            app.DefaultButton.Text = 'Default';
            app.DefaultButton.Interpreter = 'html';
            app.DefaultButton.Value = true;
            app.DarkButton = uiradiobutton(app.ThemeButtonGroup);
            app.DarkButton.Text = 'Dark';
            app.DarkButton.Interpreter = 'html';
            app.RandomButton = uiradiobutton(app.ThemeButtonGroup);
            app.RandomButton.Text = 'Random';
            app.RandomButton.Interpreter = 'html';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end